classdef arda < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DisplayPanel                    matlab.ui.container.Panel
        TextArea2                       matlab.ui.control.TextArea
        TextArea2Label                  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TextAreaLabel                   matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CreateProfileButton             matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        InitalAirTempsimEditField       matlab.ui.control.NumericEditField
        InitalAirTempsimEditFieldLabel  matlab.ui.control.Label
        RunButton                       matlab.ui.control.StateButton
        IncludeArduinoHardwareCheckBox  matlab.ui.control.CheckBox
        IncludeNIhardwareCheckBox       matlab.ui.control.CheckBox
        SimulationonlyCheckBox          matlab.ui.control.CheckBox
        StepsEditField                  matlab.ui.control.NumericEditField
        StepsEditFieldLabel             matlab.ui.control.Label
        AlltemperaturesinCelsiusLabel   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TimeStepsecEditField            matlab.ui.control.NumericEditField
        TimeStepsecEditFieldLabel       matlab.ui.control.Label
        InitialHeaterTempEditField      matlab.ui.control.NumericEditField
        InitialHeaterTempEditFieldLabel  matlab.ui.control.Label
        AmbientTempEditField            matlab.ui.control.NumericEditField
        AmbientTempEditFieldLabel       matlab.ui.control.Label
        RelativeHumidityEditField       matlab.ui.control.NumericEditField
        RelativeHumidityLabel           matlab.ui.control.Label
        SetpointsLabel                  matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties 
        profile_app
        settings {isstruct}
        % Time step
        Ts {mustBeNumeric} = 1

        % Controller gains
        Kp {mustBeNumeric} = 1
        Ki {mustBeNumeric} = 0
        Kd {mustBeNumeric} = 0

        % Derivative low-pass filter time constant
        tau {mustBeNumeric} = 0

        % Output limits
        min_output {mustBeNumeric} = 0
        max_output {mustBeNumeric} = 0

        % Controller "memory"
        integrator {mustBeNumeric} = 0
        previous_error {mustBeNumeric} = 0           % Required for integrator
        differentiator {mustBeNumeric} = 0
        previous_measurement {mustBeNumeric} = 0     % Required for differentiator
        integral_of_error {mustBeNumeric} = 0
        proportional {mustBeNumeric} = 0
        derivative_of_measurement {mustBeNumeric} = 0

        % Controller output
        out {mustBeNumeric} = 0

        x_0
        constants
        specific_heat_fit_obj
        dialog_box
        fig
        P_h_out
        T_g_out
        T_h_out
        T_o_out
        p_v_out
        p_a_out
            phi_out
        p_s_out
        q_out
        ni
        dht22
        arduino_obj
        draw_times
    end

    methods (Access = private)

    end

    methods (Access = public)

        function [output] = arda_numerical(app)
            %% [output] = arda_numerical(settings)
            % Inputs:
            %   A structure with the following fields:
            %   setpoints       : Structure with the following fields:
            %       T           : Temperature setpoint (C)
            %       phi         : Relative humidity setpoint (%)
            %   T_g0_sim        : Initial gas mixture temperature for simulations (C)
            %   T_o             : Outside (ambient) temperature (C)
            %   T_h0            : Initial temperature of the heater (C)
            %   T_s             : Length of time step (s)
            %   N               : Number of steps
            %   off             : Iteration at which to stop the simulation
            %   simulation      : Indicator to run simulation or include hardware (T/F)
            %   ni              : Indicator to include NI hardware (T/F)
            %   arduino         : Indicator to include Arduino hardware (T/F)
            %
            % Outputs:
            %   output          : A table with the following variables
            %       T_g         : Temperature of the gas mixture (C)
            %       T_h         : Temperature of the heater (C)
            %       T_o         : Outside (ambient) temperature (C)
            %       p_v         : Partial pressure of vapor (kPa)
            %       p_a         : Partial pressure of dry air (kPa)
            %       q           : Unused
            %       phi         : Relative Humidity (%)
            %       p_s         : Saturation Vapor Pressure of gas mixture (kPa)
            %       P_h         : Power setting of the heater (commanded)
            %
            % Example:
            % settings = struct(...
            %     'delta_T', 20,...
            %     'T_o', 30,...
            %     'T_h0', 50,...
            %     'T_s', 1,...
            %     'N', 180,...
            %     'off', 1e6,...
            %     'simulation', false,...
            %     'ni', true,...
            %     'arduino', false);
            %
            % data = arda_numerical(settings);
            %
            %% Setup Arduino

            %% Setup Hardware
            try
                if ~app.settings.simulation
                    %% Setup Arduino
                    if app.settings.arduino
                        app.arduino_obj = arduino('COM4' ,'Uno', 'Libraries', {'Adafruit/DHT22'});

                        % Heater
                        configurePin(app.arduino_obj,'D2','DigitalOutput');

                        % Temperature / Humidity
                        app.dht22 = addon(app.arduino_obj, 'Adafruit/DHT22', 'A0');
                    end

                    %% Setup NI
                    if app.settings.ni
                        app.ni=daq("ni");
                        app.ni.Rate = 100;
                        app.ni.addinput("cDAQ1Mod8","ai1","Thermocouple");
                        app.ni.addinput("cDAQ1Mod8","ai3","Thermocouple");
                        %     ni.addinput("cDAQ1Mod8","ai4","Thermocouple");
                        for j = 1:length(app.ni.Channels)
                            app.ni.Channels(j).ThermocoupleType = "J";
                        end
                    end
                end
            catch er
                disp('Issue setting up hardware.')
                error(er.message)
            end

            %% Create a sensor object to store data
            sensor1 = sensor();
            sensor1.wire = '1234';

            %% Memory Preallocation
            try
                app.T_g_out = zeros(app.settings.max_iterations, 1);
                app.T_h_out = zeros(app.settings.max_iterations, 1);
                app.T_o_out = zeros(app.settings.max_iterations, 1);
                app.p_v_out = zeros(app.settings.max_iterations, 1);
                app.p_a_out = zeros(app.settings.max_iterations, 1);
                app.q_out = zeros(app.settings.max_iterations, 1);
                app.phi_out = zeros(app.settings.max_iterations, 1);
                app.p_s_out = zeros(app.settings.max_iterations, 1);
                app.P_h_out = zeros(app.settings.max_iterations, 1);
                %                 m_steam = zeros(app.settings.max_iterations, 1);
                app.x_0 = zeros(12,1);
                app.constants = zeros(1,4);
               
                app.draw_times = 1:10:app.settings.max_iterations;
            catch er
                disp(er)
                error(er.message)
            end

            %% Do Everything
            app.progress_dialog;
            app.define_constants
            app.x_0 = app.set_initial_conditions;


            app.define_temperature_controller
            %             define_relative_humidity_controller
            app.setup_progress_dialog

            %             setpoint_T = app.settings.setpoints.T + 273.15;

            app.main_loop

            app.convert_to_C

            %             app.create_plots(app.T_g_out, T_h_out, T_o_out, p_v_out, p_a_out, q_out,...
            %                 phi_out, p_s_out, P_h_out, setpoint_T, app.settings.max_iterations, m_steam)

            close(app.dialog_box)
            close(app.fig)
            output = table( ...
                app.T_g_out, ...
                app.T_h_out, ...
                app.T_o_out, ...
                app.p_v_out, ...
                app.p_a_out, ...
                app.q_out,...
                app.phi_out, ...
                app.p_s_out, ...
                app.P_h_out, ...
                'VariableNames', {'T_g', 'T_h', 'T_o', 'p_v', 'p_a', ...
                'q', 'phi', 'p_s', 'P_h'});

        end
        %% Define Nested Functions

        %% Progress dialog
        function progress_dialog(app)
            app.fig = uifigure('HandleVisibility', 'on');
            app.dialog_box = uiprogressdlg(app.fig, "Title", "MATLAB is thinking", "Message",...
                "Starting...", "Cancelable", "on");%, "Indeterminate", "on");
            drawnow
        end

        %% Define constants
        function define_constants(app)
            R_a = 287.058;      % J/(kg*K), Specific Gas Constant, dry air
            R_v = 461.495;      % J/(kg*K), Specific Gas Constant, water vapor
            R_go = 0.03;        % K/W, Thermal resistance of the gas mixture to outside interface (1 inch insulation)
            R_hg = 0.005;       % K/W, Thermal resistance of the heater to gax mixture interface
            app.constants = [R_a, R_v, R_go, R_hg];

            % Create a vector for specific heat of air
            % Ref: https://www.me.psu.edu/cimbala/me433/Links/Table_A_9_CC_Properties_of_Air.pdf
            if exist('specific_heat_table.mat', 'file')
                S = load('specific_heat_table.mat','specific_heat_fit_obj');
                app.specific_heat_fit_obj = S.specific_heat_fit_obj;
            else
                [app.specific_heat_fit_obj, ~] = calculate_specific_heat_air();
            end
            
            function [specific_heat_fit_obj, gof] = calculate_specific_heat_air()
                temp_data = 273.15 + [0:5:50, 60:10:100, 120:20:200]';
                c_data = [1006*ones(1,3) 1007*ones(1,10) 1008*ones(1,2) 1009 1011 1013 ...
                    1016 1019 1023]';

                [xData, yData] = prepareCurveData(temp_data, c_data);
                ft = fittype( 'poly8' );
                [specific_heat_fit_obj, gof] = fit(xData, yData, ft, 'Normalize', 'on' );

                save specific_heat_table.mat specific_heat_fit_obj gof
            end
        end

        %% Set initial conditions
        function x_0 = set_initial_conditions(app)
            c2k = 273.15;
            if app.settings.simulation
                T_g0 = app.settings.T_g0_sim + c2k;
                T_h0 = app.settings.T_h0 + c2k;
            elseif ~app.settings.simulation && app.settings.ni
                daq_data = read(app.ni);
                T_g0 = daq_data.cDAQ1Mod8_ai1 + c2k;
                T_h0 = daq_data.cDAQ1Mod8_ai3 + c2k;
            end

            T_o0 = app.settings.T_o + c2k;
            p_g0 = 101.325;     % kPa, Initial/constant pressure of the control volume.
            V_g0 = 0.25;        % m^3, Initial/constant volume of aRDA "control volume"

            % Calculate initial saturation pressure
            %p_s0 = calculate_saturation_pressure(T_g0);
            p_s0 = XSteam('psat_T', T_g0);

            % kg/m^3
            rho_a0 = XDryAir('tK', T_g0, 'rho', p_g0);

            % kg, mass of dry air
            m_a0 = rho_a0 * V_g0;

            % kg, mass of vapor
            % m_v0 = m_a0 * phi_0 * 0.622 * (p_s0 / (p_g0 - p_s0));
            rho_v0 = XSteam('rho_pt', p_g0/100, T_g0-273.15); %#ok<NASGU>
            % rho_v0 = 0.59;
            % m_v0 = rho_v0 * V_g0;
            m_v0 = 0;

            % Initial humidity ratio
            % m_v0 = phi_0 * p_s0 * V_g0 / (R_v * T_g0);

            % q_0 = m_v0 / (m_a0 + m_v0);

            % kPa, Initial partial pressure of water vapor
            % p_v0 = q_0 * p_g0/(q_0 + 0.622);
            % p_v0 = rho_v0 * T_g0 / 0.0022;
            p_v0 = 25.7;

            % kPa, Initial partial pressure of air
            p_a0 = p_g0 - p_v0;

            % percent, Initial relative humidity
            if ~app.settings.simulation && app.settings.arduino
                phi_0 = readHumidity(app.dht22);
                if isnan(phi_0)
                    %error('Try disconnecting and reconnecting Arduino DHT22 Temp/Humidity Sensor')
                end
            else
                phi_0 =  p_v0 / p_s0;
            end
            % percent of moisture by volume
            pmv = p_v0 / p_g0; %#ok<NASGU>

            %
            q_0 = 0; % 0.622 * pmv / ((1 - pmv) + 0.622 * pmv);

            % Initial state vector
            x_0 = [T_g0; T_h0; T_o0; p_g0; p_v0; p_a0; V_g0; q_0; phi_0; p_s0; m_v0; m_a0];
        end

        %% Define the temperature controller
        function define_temperature_controller(app)


            % Set the time step
            app.Ts = app.settings.T_s;

            app.tau = 2532;
            tau_dead = 10;
            P = (197.1 - 97.1)/97.1; % Percent change of input
            N = ((25.81 - 22.87)/22.87) / app.tau; % Percent change of output / tau
            L = tau_dead;
            R = tau_dead / app.tau;
            K_0 = P/(N*L);

            % Cohen-Coon 1(only for self-regulating processes, like flow, temp, pressure)
            app.Kp = K_0 * (1.33 + R/4);
            app.Ki = (L * (30 + 3*R) / (9 + 20 * R));
            app.Kd = (4 * L / (11 + 2 * R));
            %                 app.tau = tau;
            app.min_output = 0;
            if app.settings.simulation
                app.max_output = 11000; % Max watts from heater
            elseif ~app.settings.simulation && app.settings.arduino
                app.max_output = 1;
            end
        end

        %% Initialize the relative humidity controller
        %              function define_relative_humidity_controller(~)
        %
        %                 pid_phi.Ts = app.settings.T_s;
        %                 pid_phi.tau = 59;
        %                 tau_dead = 0.1;
        %                 P2 = (0.1001 - 0.001)/0.001;
        %                 N2 = ((0.3 - 0.2)/0.2) / pid_phi.tau;
        %                 L2 = 0.1; % tau_dead
        %                 R2 = tau_dead / pid_phi.tau;
        %                 K_02 = P2 / (N2 * L2);
        %
        %                 pid_phi.Kp = K_02 * (1.33 + R2/4);
        %                 pid_phi.Ki = (L2 * (30 + 3*R2) / (9 + 20 * R2));
        %                 pid_phi.Kd = (4 * L2 / (11 + 2 * R2));
        %                 pid_phi.min_output = 0;
        %                 pid_phi.max_output = 1;


        %% Setup progress dialog
        function setup_progress_dialog(app)
            app.dialog_box.Message = "Processing...";
            drawnow
        end

        %% Main loop
        function main_loop(app)

            measurement = app.x_0(1);
            %                 measurement_phi = x_0(9);
            %                 setpoint_phi = 0.33;

            %%
            for i = 1:app.settings.max_iterations

                %% Controller

                % Calculate output for temperature controller
                app.calculate_controller_output(app.settings.setpoints.T, measurement);

                %                 fprintf('Measurement: %.1f\n', measurement)
                %                 fprintf('Setpoint: %.1f\n', setpoint_T)

                % When using the Arduino, the heater can be on or off
                if ~app.settings.simulation && app.settings.arduino
                    if app.out > 0
                        % Turn on the heater
                        writeDigitalPin(app.arduino_obj,'D2',1)
                    else
                        % Turn off the heater
                        writeDigitalPin(app.arduino_obj,'D2',0)
                    end
                    pause(1) % Don't burn out the Arduino relay
                end

                %                     % Calculate output for humidity controller
                %                     pid_phi = calculate_controller_output(...
                %                         pid_phi, setpoint_phi, measurement_phi);

                % Record the power setting of the heater at each time step
                app.P_h_out(i) = app.out;
                %                     m_steam(i) = pid_phi.out;

                %% Plant Model
                % On the first time through the loop, use the x_0 above. After that,
                % the initial values to give to the ode are the last ones it gave.
                if i >= 2
                    app.x_0 = y(end,:);
                end

                % Call ode solver
                [~, y] = ode23(@(t,y) odefun(t, y, app.specific_heat_fit_obj), [0 app.Ts], app.x_0);

                % Capture sensor measurements
                if ~app.settings.simulation
                    if app.settings.ni
                        daq_data = read(app.ni);
                        y(end,1) = daq_data.cDAQ1Mod8_ai1 + 273.15; % T_g2
                        y(end,2) = daq_data.cDAQ1Mod8_ai3 + 273.15; % T_h2
                    end
                    if app.settings.arduino
                        y(end,9) = readHumidity(app.dht22);
                    end
                end
                measurement = y(end,1);
                %                     measurement_phi = y(end,9);
                app.T_g_out(i) = y(end,1);
                app.T_h_out(i) = y(end,2);
                app.T_o_out(i) = y(end,3);
                app.p_v_out(i) = y(end,5);
                app.p_a_out(i) = y(end,6);
                app.q_out(i)   = y(end,8);
                app.phi_out(i) = y(end,9);
                app.p_s_out(i) = y(end,10);


                xx = 1:length(app.T_g_out);
                yy = app.T_g_out - 273.15;
                yy(yy<0) = NaN;
                plot(app.UIAxes, xx, yy, '.b')
                grid(app.UIAxes,"on")
                hold(app.UIAxes,"on")
                axis(app.UIAxes,[1 app.settings.max_iterations 20 50])


                if ismember(i, app.draw_times)
                    app.dialog_box.Value = i/app.settings.max_iterations;
                    drawnow
                    if app.dialog_box.CancelRequested
                        break
                    end
                end
                
            end
            
            %% ODE of thermal system
            function [delta] = odefun(~, x, fit_obj)
                %% Setup and unpacking
                delta = zeros(length(x), 1);
                p_v_dot = 0;
                p_a_dot = 0;
                V_g_dot = 0;


                % System variables
                T_g = x(1); % T_g0  1) K, initial T_g
                T_h = x(2); %#ok<NASGU> % T_h0; 2) K, Initial heater temp, T_h
                T_o = x(3); % T_o0; 3) K, Initial outside / ambient temp, T_o
                p_g = x(4); % p_g0; 4) kPa, Initial total pressure of gas mixture
                p_v = x(5); % p_v0; 5) kPa, Initial partial pressure of water vapor
                p_a = x(6); % p_a0; 6) kPa, Initial partial pressure of air
                V_g = x(7); % V_g0; 7) m^3, Initial volume of the control volume
                q   = x(8); %#ok<NASGU> % q_0;  8) Initial humidity ratio
                phi = x(9); % phi_0;9) Initial relative humidity
                p_s = x(10); % p_s0];10) Initial saturation vapor pressure
                m_v = x(11);
                m_a = x(12);

                % Constants
                C_g = fit_obj(T_g); % J/(kg*K), Specific heat capacity of
                C_h = 4660;         % J/(kg*K), Specific heat capacity of the heater
                c_p = 0.718;        % Specific heat of air at constant pressure
                m_h = 2.8;          % kg, Mass of the heater (about 6 lbs)
                R_a = app.constants(1);
                R_v = app.constants(2);
                R_go = app.constants(3);
                R_hg = app.constants(4);

                %% dT_o/dt
                delta(3) = 0; % Constant ambient temperature outside the system

                %% dp_g/dt
                delta(4) = 0; % Constant total pressure at ambient
                p_g2 = p_g + delta(4);

                %% dV_g/dt
                delta(7) = 0;  % Constant volume for the gas mixture (aRDA volume is fixed)
                V_g2 = V_g + delta(7);

                %% Change in m_a
                % kg/m^3, density of dry air at 20C and 1 atm
                rho_a = XDryAir('tK', T_g, 'rho', p_g);

                % kg, mass of dry air
                m_a2 = rho_a * V_g2;

                delta(12) = m_a2 - m_a;

                %% dT_g/dt

                c1 = 1/(C_g*R_hg) + 1/(C_g*R_go);

                c2 = (c_p/C_g) * ((p_a * V_g/R_a) + (p_v * V_g/R_v));

                c3 = (c_p/C_g) * ((p_a_dot * x(7) + x(6) * V_g_dot)/ R_a ...
                    + ((p_v_dot * x(7) + x(5) * V_g_dot))/R_v);

                % mass_flow_in = 0;
                delta(1) = -(c3 + c1)*x(1) + c2/x(1) + x(2)/(C_g*R_hg) + x(3)/(C_g*R_go);
                T_g2 = T_g + delta(1);

                delta(1) = T_g2 - T_g;

                %% Change in m_v
                % Call XSteam with pressure in bar (kPa/100), and T in C
                p_g_bar = p_g2/100; % convert pressure to bars
                T_g_C = T_g2 - 273.15; % convert temperature to C
                rho_v2 = XSteam('rho_pt', p_g_bar, T_g_C);
                m_v2 = rho_v2 * V_g2;
                delta(11) = (m_v2 - m_v); % + pid_phi.out; % kg per time step
                % m_v2 = m_v + delta(11);

                %% dT_h/dt
                delta(2) = x(1)/(C_h * m_h * R_hg)...
                    - x(2)/(C_h * m_h * R_hg)...
                    + app.out/(C_h * m_h);
                %     delta(2) = T_h2 - T_h;
                %% dp_s/dt (change in saturation pressure)
                % p_s2 = calculate_saturation_pressure(T_g2);
                p_s2 = XSteam('psat_T', T_g2);
                delta(10) = p_s2 - p_s;

                %% dp_v/dt
                % p_v2 = q2 * p_g2/(q2 + 0.622);

                p_v2 = rho_v2 * T_o / 0.0022;
                % h = XSteam('hv_p', p_g2/100);
                % s = XSteam('s_pt', p_g2/100, T_g2-273.15);
                % p_v2 = XSteam('p_hs', h, s);
                % if any(isnan([h s p_v2]))
                %     keyboard
                % end
                if p_v2 > p_g2
                    p_v2 = p_v;
                end
                delta(5) = p_v2 - p_v;

                %% dq/dt
                % q2 = m_v2 / (m_a2 + m_v2);
                % percent of moisture by volume
                % pmv2 = p_v2 / p_g2;
                % q2 = 0.622 * pmv2 / ((1 - pmv2) + 0.622 * pmv2);
                delta(8) = 0;

                %% dp_a/dt
                % p_a2 = p_g2 - p_v2;
                delta(6) = -delta(5);

                %% dphi/dt (change in relative humidity)
                % m_v2 / calculate_saturation_density(T_g2);
                phi2 =  p_v2 / p_s2;
                % phi2 =  q2 / (0.622 * (p_s2 / (p_g2 - (1 - 0.622)*p_s2)));
                % phi2 = (m_v2/m_a2) / (0.622 * p_s2 / (p_g2 - p_s2))

                delta(9) = phi2 - phi;

            end
        end

        %% Convert to Celsius
        function convert_to_C(app)
            c2k = 273.15;
            app.T_g_out = app.T_g_out - c2k;
            app.T_h_out = app.T_h_out - c2k;
            app.T_o_out = app.T_o_out - c2k;
        end


        function create_plots(app)
            %% Humidity plot
            figure
            % yyaxis left
            % plot(q_out)
            % ylabel('Humidity Ratio')
            %
            % yyaxis right
            plot(app.phi_out)
            ylabel('Relative Humidity')
            xlabel('Time')
            grid on


            %% Pressure plot
            figure
            subplot(3,1,1)
            plot(app.p_v_out)
            title('Pressure (kPa)')
            grid on
            legend({'p_v'}, 'Location', 'best')
            ylabel('p_v')

            subplot(3,1,2)
            plot(app.p_a_out)
            grid on
            legend({'p_a'}, 'Location', 'best')
            ylabel('p_a')

            subplot(3,1,3)
            plot(app.p_s_out)
            grid on
            xlabel('Time')
            legend({'p_s'}, 'Location', 'best')
            ylabel('p_s')

            %% Temperature plot
            figure; subplot(2,1,1)

            % Gas mixture temperature
            plot(app.T_g_out); hold on; grid on

            % Heater temperature
            plot(app.T_h_out)

            % Plot a horizontal line at the setpoint
            plot(setpoint_T * ones(max_iterations,1), '--k')

            % Plot a horizontal line at the outside temperature
            plot(app.T_o_out, '--m')
            ylabel('Temp. (C)')
            legend({'T_g', 'T_h', 'SP', 'T_o'}, 'Location', 'best')
            xlim([1 max_iterations])

            % Power setting of the heater
            subplot(2,1,2)
            plot(app.P_h_out); grid on
            legend({'P_h'},'Location','best')
            ylabel('Power (W)')
            xlim([1 max_iterations])

        end


        function calculate_controller_output(app, setpoint, measurement)

            % Error signal
            error = setpoint - measurement;
            app.integral_of_error = app.integral_of_error + error * app.Ts;
            app.derivative_of_measurement = (measurement - app.previous_measurement)...
                / app.Ts;

            % Proportional
            app.proportional = app.Kp * error;

            % Integral
            app.integrator = app.Ki / app.tau * app.integral_of_error;

            % Derivative (band limited differentiator)
            % Doing derivative on measurement instead of error to avoid "kick" on
            % setpoint changes.
            app.differentiator = app.Kd * app.tau * app.derivative_of_measurement;

            % Compute limits and apply limits
            app.out = app.proportional + app.integrator + app.differentiator;

            % Anti-windup
            % Check upper limit
            if app.out > app.max_output

                app.out = app.max_output;
                app.integral_of_error = app.integral_of_error - error * app.Ts;

            end

            % Check lower limit
            if app.out < app.min_output

                app.out = app.min_output;
                app.integral_of_error = app.integral_of_error - error * app.Ts;

            end


            % Store variables for subsequent iterations
            app.previous_error = error;
            app.previous_measurement = measurement;

        end
        
        function update_profile(app, x, y)
            app.TextArea.Value = num2str(x(1));
            app.TextArea2.Value = num2str(y(1));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin("base","clear")
            
            setpoints = struct('T', 40, 'phi', 0.50);
            app.settings = struct(...
                'setpoints', setpoints,...
                'T_g0_sim', 20,...
                'T_o', 20,...
                'T_h0', 50,...
                'T_s', 1,...
                'N', 30,... % 30=1min irl
                'off', 1e4,...
                'simulation', false,...
                'ni', true,...
                'arduino', true);
            app.InitalAirTempsimEditField.Enable = false;

            app.TemperatureEditField.Value = app.settings.setpoints.T;
            app.RelativeHumidityEditField.Value = app.settings.setpoints.phi;
            app.InitalAirTempsimEditField.Value = app.settings.T_g0_sim;
            app.AmbientTempEditField.Value = app.settings.T_o;
            app.InitialHeaterTempEditField.Value = app.settings.T_h0;
            app.TimeStepsecEditField.Value = app.settings.T_s;
            app.StepsEditField.Value = app.settings.N;
            app.SimulationonlyCheckBox.Value = app.settings.simulation;
            app.IncludeNIhardwareCheckBox.Value = app.settings.ni;
            app.IncludeArduinoHardwareCheckBox.Value = app.settings.arduino;
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            if value == true
                app.RunButton.Enable = false;
                app.settings.setpoints.T = app.TemperatureEditField.Value;
                app.settings.setpoints.phi = app.RelativeHumidityEditField.Value;
                app.settings.T_g0_sim = app.InitalAirTempsimEditField.Value;
                app.settings.T_o = app.AmbientTempEditField.Value;
                app.settings.T_h0 = app.InitialHeaterTempEditField.Value;
                app.settings.T_s = app.TimeStepsecEditField.Value;
                app.settings.N = app.StepsEditField.Value;
                app.settings.simulation = app.SimulationonlyCheckBox.Value;
                app.settings.ni = app.IncludeNIhardwareCheckBox.Value;
                app.settings.arduino = app.IncludeArduinoHardwareCheckBox.Value;
                app.settings.max_iterations = app.settings.N/app.settings.T_s;
                app.settings.stop_at = app.settings.off/app.settings.T_s;
                app.TotalIterationsTextArea.Value = num2str(app.settings.max_iterations);
                app.DropdeadatTextArea.Value = num2str(app.settings.stop_at);
                app.EstruntimeminsTextArea.Value = num2str(app.settings.N/30);
                %                 assignin('base',"settings", app.settings)
                %                 evalin('base','data = arda_numerical(settings)')
                data = app.arda_numerical();
                save("data_from_app.mat","data")
            end
            app.RunButton.Value = false;
            app.RunButton.Enable = true;
        end

        % Value changed function: SimulationonlyCheckBox
        function SimulationonlyCheckBoxValueChanged(app, event)
            value = app.SimulationonlyCheckBox.Value;
            if value == true
                app.InitalAirTempsimEditField.Enable = true;
            else
                app.InitalAirTempsimEditField.Enable = false;
            end
        end

        % Button pushed function: CreateProfileButton
        function CreateProfileButtonPushed(app, event)
            app.CreateProfileButton.Enable = 'off';
            app.profile_app = the_profile_app(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete both apps
            delete(app.profile_app)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Steps vs Temperature')
            xlabel(app.UIAxes, 'Steps')
            ylabel(app.UIAxes, 'Temp. (C)')
            zlabel(app.UIAxes, 'Z')
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.TemperatureEditFieldLabel = uilabel(app.SettingsPanel);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.SetpointsLabel = uilabel(app.SettingsPanel);
            app.SetpointsLabel.Text = 'Setpoints';
            app.RelativeHumidityLabel = uilabel(app.SettingsPanel);
            app.RelativeHumidityLabel.Text = 'Relative Humidity (%)';
            app.RelativeHumidityEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.AmbientTempEditFieldLabel = uilabel(app.SettingsPanel);
            app.AmbientTempEditFieldLabel.Text = 'Ambient Temp.';
            app.AmbientTempEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.InitialHeaterTempEditFieldLabel = uilabel(app.SettingsPanel);
            app.InitialHeaterTempEditFieldLabel.Text = 'Initial Heater Temp.';
            app.InitialHeaterTempEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.TimeStepsecEditFieldLabel = uilabel(app.SettingsPanel);
            app.TimeStepsecEditFieldLabel.Text = 'Time Step (sec)';
            app.TimeStepsecEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.Label = uilabel(app.SettingsPanel);
            app.Label.Text = '.........................................................';
            app.AlltemperaturesinCelsiusLabel = uilabel(app.SettingsPanel);
            app.AlltemperaturesinCelsiusLabel.Text = 'All temperatures in Celsius.';
            app.StepsEditFieldLabel = uilabel(app.SettingsPanel);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.SimulationonlyCheckBox = uicheckbox(app.SettingsPanel);
            app.SimulationonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimulationonlyCheckBoxValueChanged, true);
            app.SimulationonlyCheckBox.Text = 'Simulation only';
            app.IncludeNIhardwareCheckBox = uicheckbox(app.SettingsPanel);
            app.IncludeNIhardwareCheckBox.Text = 'Include NI hardware';
            app.IncludeArduinoHardwareCheckBox = uicheckbox(app.SettingsPanel);
            app.IncludeArduinoHardwareCheckBox.Text = 'Include Arduino Hardware';
            app.RunButton = uibutton(app.SettingsPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.InitalAirTempsimEditFieldLabel = uilabel(app.SettingsPanel);
            app.InitalAirTempsimEditFieldLabel.Text = 'Inital Air Temp. (sim)';
            app.InitalAirTempsimEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.CreateProfileButton = uibutton(app.DisplayPanel, 'push');
            app.CreateProfileButton.ButtonPushedFcn = createCallbackFcn(app, @CreateProfileButtonPushed, true);
            app.CreateProfileButton.Text = 'Create Profile';
            app.Label_2 = uilabel(app.DisplayPanel);
            app.Label2 = uilabel(app.DisplayPanel);
            app.Label2.Text = 'Label2';
            app.TextAreaLabel = uilabel(app.DisplayPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.DisplayPanel);
            app.TextArea2Label = uilabel(app.DisplayPanel);
            app.TextArea2Label.Text = 'Text Area2';
            app.TextArea2 = uitextarea(app.DisplayPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arda

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end