classdef the_profile_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        DoneButton       matlab.ui.control.Button
        EditField2       matlab.ui.control.NumericEditField
        EditField2Label  matlab.ui.control.Label
        EditField        matlab.ui.control.NumericEditField
        EditFieldLabel   matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end


    properties (Access = private)
        calling_app
    end

    methods (Access = private, Static = true)

        function dragpoints(ax)
            % figure;
            %xData,yData,xLower,xUpper,yLower,yUpper
            x = 1:10;
            y = 1:10;

            %ax = axes('xlimmode','manual','ylimmode','manual');
            ax.XLim = [0 10];
            ax.YLim = [0 10];

            %can change the marker size or marker type to make it more visible.
            %Currently is set to small points at a size of 2 so is not very visible.
            line(ax,x,y,...
                'marker','o',...
                'markersize',5,...
                'LineWidth',2,...
                'hittest','on',...
                'buttondownfcn',@clickmarker)


            function clickmarker(src,~)
                set(ax,'windowbuttonmotionfcn',{@dragmarker,src})
                set(ax,'windowbuttonupfcn',@stopdragging)
            end


            function dragmarker(~,~,src)

                %get current axes and coords
                %h1=gca;
                coords=get(ax,'currentpoint');

                %get all x and y data
                x=ap.UIAxes.Children.XData;
                y=ap.UIAxes.Children.YData;

                %check which data point has the smallest distance to the dragged point
                x_diff=abs(x-coords(1,1,1));
                y_diff=abs(y-coords(1,2,1));
                [~, index]=min(x_diff+y_diff);

                %create new x and y data and exchange coords for the dragged point
                x_new=x;
                y_new=y;
                x_new(index)=coords(1,1,1);
                y_new(index)=coords(1,2,1);

                assignin('base','x',x_new);
                assignin('base','y',y_new);
                %update plot
                set(src,'xdata',x_new,'ydata',y_new);
            end

            function stopdragging(ax)
                set(ax,'windowbuttonmotionfcn','')
                set(ax,'windowbuttonupfcn','')
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            % Store main app object
            app.calling_app = main_app;

            x = 1:10;
            y = x;
            ax = app.UIAxes;
            app.dragpoints(ax)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Call main app public function
            update_profile(app.calling_app, app.EditField.Value, app.EditField2.Value)

            % Delete the profile app
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.calling_app.CreateProfileButton.Enable = 'on';
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = the_profile_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end