classdef controlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TextArea                  matlab.ui.control.TextArea
        TextAreaLabel             matlab.ui.control.Label
        LineStyleDropDown         matlab.ui.control.DropDown
        LineStyleDropDownLabel    matlab.ui.control.Label
        LineColorDropDown         matlab.ui.control.DropDown
        LineColorDropDownLabel    matlab.ui.control.Label
        MarkerStyleDropDown       matlab.ui.control.DropDown
        MarkerStyleDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        listener_handle1 % Description
    end

    properties (Access = public, SetObservable = true)
        PlotWindow  % holds the reference tot he plot window
        done = false
    end

    methods (Access = private)

        function updatePlot(app)
            mStyle = app.MarkerStyleDropDown.Value;
            lStyle = app.LineStyleDropDown.Value;
            lColor = app.LineColorDropDown.Value;
            d = app.PlotWindow.DATA;

            switch mStyle
                case 'none'
                    d.marker = 'none';
                case 'circle'
                    d.marker = 'o';
                case 'diamond'
                    d.marker = 'diamond';
                case 'point'
                    d.marker = '.';
            end

            switch lStyle
                case 'none'
                    d.linestyle = 'none';
                case 'solid'
                    d.linestyle = '-';
                case 'dashed'
                    d.linestyle = '--';
                case 'dotted'
                    d.linestyle = ':';
            end
            
            d.color = lColor;
            
            % Change the DATA property in plotGUI triggering the listener
            app.PlotWindow.DATA = d;
        end
        
        function stuff_to_do_when_other_app_is_done(app)
            app.TextArea.Value = 'Other app is done.';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.PlotWindow = plotGUI(app);
            % add and enable postset listener for property
            app.listener_handle1 = addlistener(app, 'done', 'PostSet', ...
                @(s,e) app.stuff_to_do_when_other_app_is_done);
            app.listener_handle1.Enabled = true;
        end

        % Value changed function: LineStyleDropDown
        function LineStyleDropDownValueChanged(app, event)
            app.updatePlot();
        end

        % Value changed function: LineColorDropDown
        function LineColorDropDownValueChanged(app, event)
            app.updatePlot();            
        end

        % Value changed function: MarkerStyleDropDown
        function MarkerStyleDropDownValueChanged(app, event)
            app.updatePlot();            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MarkerStyleDropDownLabel = uilabel(app.UIFigure);
            app.MarkerStyleDropDownLabel.Text = 'Marker Style';
            app.MarkerStyleDropDown = uidropdown(app.UIFigure);
            app.MarkerStyleDropDown.Items = {'none', 'circle', 'diamond', 'point'};
            app.MarkerStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerStyleDropDownValueChanged, true);
            app.MarkerStyleDropDown.Value = 'none';
            app.LineColorDropDownLabel = uilabel(app.UIFigure);
            app.LineColorDropDownLabel.Text = 'Line Color';
            app.LineColorDropDown = uidropdown(app.UIFigure);
            app.LineColorDropDown.Items = {'red', 'green', 'blue', 'black'};
            app.LineColorDropDown.ValueChangedFcn = createCallbackFcn(app, @LineColorDropDownValueChanged, true);
            app.LineColorDropDown.Value = 'red';
            app.LineStyleDropDownLabel = uilabel(app.UIFigure);
            app.LineStyleDropDownLabel.Text = 'Line Style';
            app.LineStyleDropDown = uidropdown(app.UIFigure);
            app.LineStyleDropDown.Items = {'none', 'solid', 'dashed', 'dotted'};
            app.LineStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @LineStyleDropDownValueChanged, true);
            app.LineStyleDropDown.Value = 'none';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controlGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end