classdef plotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataChangedListener % listens for when DATA is changed
        main_app
    end
    
    properties (Access = public, SetObservable = true)
        DATA % struct with data to plot
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            delete(app.UIAxes.Children);
            line(app.DATA, 'parent', app.UIAxes);
            app.done();
        end
        
        function done(app)
            app.main_app.done = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.main_app = main_app;

            data = struct();
            data.xdata = linspace(0,1,1001);
            data.ydata = sin(data.xdata.*2.*pi*10);
            data.color = app.UIAxes.ColorOrder(1,:);


            app.DATA = data;

            % plot
            line(data,'parent', app.UIAxes);

            % add and enable postset listener for data property
            app.dataChangedListener = addlistener(app, 'DATA', 'PostSet', ...
                @(s,e) app.updatePlot);
            app.dataChangedListener.Enabled = true;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end