classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        ExitButton  matlab.ui.control.Button
        SaveButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        saved = false 
        time
        setpoint
    end
    
    methods (Access = private)
        
        function response = close_uiconfirm(~)
            response = "No";
        end
        
        function copy_stuff(app)
            app.time = 1:10;
            app.setpoint = 1:10;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
 
            if ~app.saved
                try
                app.copy_stuff();
                filter = {'*.mat'};
                title = 'Save the current profile';
                default_name = ['saved_profile_' datestr(now,'yyyy_mm_dd_HHMMSS')];
                [file, path] = uiputfile(filter, title, default_name);
                
                    [filepath, filename, ext] = fileparts(fullfile(path, file));
                    if strcmp(ext, '.mat')
                        time = app.time; %#ok<ADPROPLC>
                        setpoint = app.setpoint; %#ok<ADPROPLC>
                        save(fullfile(filepath, [filename ext]), "time", "setpoint")
                        app.saved = true;
                    else
                        error('Unable to save setpoint profile.')
                    end
                catch er
                    error(er.message)
                end
            end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)

            if ~app.saved
                response = uiconfirm(app.UIFigure, "Save to .mat file?",...
                    "Save",...
                    "Options", ["Yes", "No", "Cancel"], ...
                    "CancelOption","Cancel",...
                    "DefaultOption", "Yes",...
                    "CloseFcn", @close_uiconfirm);

                if strcmp(response, "Yes")
                    app.SaveButtonPushed();
                    %update_plot(app.the_main_app, app.time, app.setpoint)
                elseif strcmp(response, "No")
                    app.copy_stuff();
                    %update_plot(app.the_main_app, app.time, app.setpoint)
                else
                    return
                end
                    
            end

            app.UIFigureCloseRequest();


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end