classdef arda_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulationparametersPanel       matlab.ui.container.Panel
        ElapsedTimeTextArea             matlab.ui.control.TextArea
        ElapsedTimeTextAreaLabel        matlab.ui.control.Label
        max_iterationsEditField         matlab.ui.control.NumericEditField
        max_iterationsEditFieldLabel    matlab.ui.control.Label
        iEditField                      matlab.ui.control.NumericEditField
        iEditFieldLabel                 matlab.ui.control.Label
        RuntimePanel                    matlab.ui.container.Panel
        StatusPanel                     matlab.ui.container.Panel
        StandbyLamp                     matlab.ui.control.Lamp
        StandbyLampLabel                matlab.ui.control.Label
        OffLamp                         matlab.ui.control.Lamp
        OffLampLabel                    matlab.ui.control.Label
        ShuttingDownLamp                matlab.ui.control.Lamp
        ShuttingDownLampLabel           matlab.ui.control.Label
        StartingupLamp                  matlab.ui.control.Lamp
        StartingupLampLabel             matlab.ui.control.Label
        RunningLamp                     matlab.ui.control.Lamp
        RunningLampLabel                matlab.ui.control.Label
        ThestateofthesystemisdisplayedontherightLabel  matlab.ui.control.Label
        TomonitoradditionalsensorsinanotherwindowselectLabel  matlab.ui.control.Label
        MoresensorsButton               matlab.ui.control.StateButton
        BiomassPanel                    matlab.ui.container.Panel
        MasstotalmeasuredkgEditField    matlab.ui.control.NumericEditField
        MasstotalmeasuredkgEditFieldLabel  matlab.ui.control.Label
        DistanceLIDARtomaterialmEditField  matlab.ui.control.NumericEditField
        DistanceLIDARtomaterialmEditFieldLabel  matlab.ui.control.Label
        UIAxes_lidar                    matlab.ui.control.UIAxes
        LabelPanel                      matlab.ui.container.Panel
        Copyright2021ForestConceptsLLCLabel  matlab.ui.control.Label
        AdvancedResearchDryingApparatusARDALabel  matlab.ui.control.Label
        VerifyprofileandselectRUNPanel  matlab.ui.container.Panel
        STOPButton                      matlab.ui.control.StateButton
        RunButton                       matlab.ui.control.StateButton
        UIAxes_profile                  matlab.ui.control.UIAxes
        EmergencyControlsPanel          matlab.ui.container.Panel
        WhatthisdoesTextArea            matlab.ui.control.TextArea
        WhatthisdoesTextAreaLabel       matlab.ui.control.Label
        EMERGENCYSTOPButton             matlab.ui.control.Button
        SelectoptionsPanel              matlab.ui.container.Panel
        CreateorloadsetpointprofilePanel  matlab.ui.container.Panel
        EditCurrentButton               matlab.ui.control.Button
        LoadExistingButton              matlab.ui.control.Button
        CreateNewButton                 matlab.ui.control.Button
        HeaterDropDown                  matlab.ui.control.DropDown
        HeaterDropDownLabel             matlab.ui.control.Label
        UsingloadcellCheckBox           matlab.ui.control.CheckBox
        UsingRHsensorCheckBox           matlab.ui.control.CheckBox
        UsingLIDARCheckBox              matlab.ui.control.CheckBox
        UsingNIHardwareCheckBox         matlab.ui.control.CheckBox
        UsingArduinoHardwareCheckBox    matlab.ui.control.CheckBox
        SimulationonlyCheckBox          matlab.ui.control.CheckBox
        ORLabel                         matlab.ui.control.Label
        ProgressBarCheckBox             matlab.ui.control.CheckBox
        max_iterationsEditField_2       matlab.ui.control.NumericEditField
        max_iterationsEditField_2Label  matlab.ui.control.Label
        LivePlotCheckBox                matlab.ui.control.CheckBox
        StatexPanel                     matlab.ui.container.Panel
        Relativehumiditydecimalx8Panel  matlab.ui.container.Panel
        phiEditField                    matlab.ui.control.NumericEditField
        phiEditFieldLabel               matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
        Massofairkgx11Panel             matlab.ui.container.Panel
        m_aEditField                    matlab.ui.control.NumericEditField
        m_aEditFieldLabel               matlab.ui.control.Label
        UIAxes_13                       matlab.ui.control.UIAxes
        Massofvaporkgx10Panel           matlab.ui.container.Panel
        m_vEditField                    matlab.ui.control.NumericEditField
        m_vEditFieldLabel               matlab.ui.control.Label
        UIAxes_14                       matlab.ui.control.UIAxes
        SaturationpressurekPax9Panel    matlab.ui.container.Panel
        p_sEditField                    matlab.ui.control.NumericEditField
        p_sEditFieldLabel               matlab.ui.control.Label
        UIAxes_15                       matlab.ui.control.UIAxes
        VolumetricairflowkgsPanel       matlab.ui.container.Panel
        flowEditField                   matlab.ui.control.NumericEditField
        flowEditFieldLabel              matlab.ui.control.Label
        UIAxes_20                       matlab.ui.control.UIAxes
        Volumem3x7Panel                 matlab.ui.container.Panel
        V_gEditField                    matlab.ui.control.NumericEditField
        V_gEditFieldLabel               matlab.ui.control.Label
        UIAxes_6                        matlab.ui.control.UIAxes
        PartialpressairkPax6Panel       matlab.ui.container.Panel
        p_aEditField                    matlab.ui.control.NumericEditField
        p_aEditFieldLabel               matlab.ui.control.Label
        UIAxes_7                        matlab.ui.control.UIAxes
        AmbientTempCx3Panel             matlab.ui.container.Panel
        T_oEditField                    matlab.ui.control.NumericEditField
        T_oEditFieldLabel               matlab.ui.control.Label
        UIAxes_10                       matlab.ui.control.UIAxes
        HeaterTempCx2Panel              matlab.ui.container.Panel
        T_hEditField                    matlab.ui.control.NumericEditField
        T_hEditFieldLabel               matlab.ui.control.Label
        UIAxes_11                       matlab.ui.control.UIAxes
        VaporpressurekPax5Panel         matlab.ui.container.Panel
        p_vEditField                    matlab.ui.control.NumericEditField
        p_vEditFieldLabel               matlab.ui.control.Label
        UIAxes_8                        matlab.ui.control.UIAxes
        PressurekPax4Panel              matlab.ui.container.Panel
        p_gEditField                    matlab.ui.control.NumericEditField
        p_gEditFieldLabel               matlab.ui.control.Label
        UIAxes_9                        matlab.ui.control.UIAxes
        AirTempCx1Panel                 matlab.ui.container.Panel
        T_gEditField                    matlab.ui.control.NumericEditField
        x1T_gLabel                      matlab.ui.control.Label
        UIAxes_12                       matlab.ui.control.UIAxes
    end


    properties (Access = private)

        profile_creator_app


    end

    properties (Access = public)

        time
        setpoints
        more_sensors_app

    end

    methods (Access = public)

        function update_plot(app)

            cla(app.UIAxes_profile)
            app.max_iterationsEditField_2.Value = app.time(end);
            app.max_iterationsEditField_2.Enable = 'off';
            plot(app.UIAxes_profile, app.time, app.setpoints)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            evalin("base","clear; close all; clc")

        end

        % Button pushed function: CreateNewButton
        function CreateNewButtonPushed(app, event)

            app.profile_creator_app = profile_creator(app);

        end

        % Button pushed function: LoadExistingButton
        function LoadExistingButtonPushed(app, event)

            filter = {'*.mat'};
            title = 'Select existing setpoint profile';
            [file, path] = uigetfile(filter, title);

            if isequal(file,0)
                return
            else
                S = load(fullfile(path,file),"time","setpoints");
                if length(S.time) == length(S.setpoints)
                    app.time = S.time;
                    app.setpoints = S.setpoints;
                    app.update_plot();
                else
                    error("Profile failed to load.")
                end
            end

        end

        % Button pushed function: EditCurrentButton
        function EditCurrentButtonPushed(app, event)
            app.profile_creator_app = profile_creator(app);
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)

            app.RunButton.Enable = "off";
            app.CreateNewButton.Enable = "off";
            app.LoadExistingButton.Enable = "off";
            app.EditCurrentButton.Enable = "off";

            bob = arda();
            bob.simulation_only = app.SimulationonlyCheckBox.Value;
            bob.using_arduino_hardware = app.UsingArduinoHardwareCheckBox.Value;
            bob.using_ni_hardware = app.UsingNIHardwareCheckBox.Value;
            bob.using_lidar = app.UsingLIDARCheckBox.Value;
            bob.using_relative_humidity_sensor = app.UsingRHsensorCheckBox.Value;
            if strcmp(app.HeaterDropDown.Value, 'none') && bob.using_ni_hardware
                bob.heater = 'ni';
            else
                bob.heater = app.HeaterDropDown.Value;
            end
            bob.using_load_cell = app.UsingloadcellCheckBox.Value;

            bob.max_iterations = app.max_iterationsEditField_2.Value;
            bob.live_plot = app.LivePlotCheckBox.Value;

            bob.using_app = true;
            bob.t = app.time;
            bob.s = app.setpoints;
            app.UIAxes_12.XLim = [0 bob.max_iterations];

            app.more_sensors_app = more_sensors(app);
            app.MoresensorsButton.Enable = "off";

            bob = fake_main(bob,"app",app);

            save('bob.mat','bob')
            disp('Done.')
            app.RunButton.Enable = "on";
            app.CreateNewButton.Enable = "on";
            app.LoadExistingButton.Enable = "on";
            app.EditCurrentButton.Enable = "on";

            app.MoresensorsButton.Enable = "on";

        end

        % Value changed function: MoresensorsButton
        function MoresensorsButtonValueChanged(app, event)

            value = app.MoresensorsButton.Value;
            if value == true

                app.more_sensors_app = more_sensors(app);
                app.MoresensorsButton.Enable = "off";

            end

        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)

            evalin("base", "notify(bob,'emergency')")
            app.EMERGENCYSTOPButton.Text = "STOPPING";
            app.EMERGENCYSTOPButton.Enable = "off";

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            response = uiconfirm(app.UIFigure, "Close?",...
                "Confirm",...
                "Options", ["Yes", "Cancel"], ...
                "CancelOption", "Cancel",...
                "DefaultOption", "Yes");

            if strcmp(response, "Yes")

                delete(app)

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StatexPanel = uipanel(app.UIFigure);
            app.StatexPanel.Title = 'State, x';
            app.AirTempCx1Panel = uipanel(app.StatexPanel);
            app.AirTempCx1Panel.Title = 'Air Temp. (C), x(1)';
            app.UIAxes_12 = uiaxes(app.AirTempCx1Panel);
            zlabel(app.UIAxes_12, 'Z')
            app.UIAxes_12.YLim = [15 40];
            app.x1T_gLabel = uilabel(app.AirTempCx1Panel);
            app.x1T_gLabel.Text = 'T_g';
            app.T_gEditField = uieditfield(app.AirTempCx1Panel, 'numeric');
            app.PressurekPax4Panel = uipanel(app.StatexPanel);
            app.PressurekPax4Panel.Title = 'Pressure (kPa), x(4)';
            app.UIAxes_9 = uiaxes(app.PressurekPax4Panel);
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_9.YLim = [95 105];
            app.p_gEditFieldLabel = uilabel(app.PressurekPax4Panel);
            app.p_gEditFieldLabel.Text = 'p_g';
            app.p_gEditField = uieditfield(app.PressurekPax4Panel, 'numeric');
            app.VaporpressurekPax5Panel = uipanel(app.StatexPanel);
            app.VaporpressurekPax5Panel.Title = 'Vapor pressure (kPa), x(5)';
            app.UIAxes_8 = uiaxes(app.VaporpressurekPax5Panel);
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_8.YLim = [0 105];
            app.p_vEditFieldLabel = uilabel(app.VaporpressurekPax5Panel);
            app.p_vEditFieldLabel.Text = 'p_v';
            app.p_vEditField = uieditfield(app.VaporpressurekPax5Panel, 'numeric');
            app.HeaterTempCx2Panel = uipanel(app.StatexPanel);
            app.HeaterTempCx2Panel.Title = 'Heater Temp. (C), x(2)';
            app.UIAxes_11 = uiaxes(app.HeaterTempCx2Panel);
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_11.YLim = [20 250];
            app.T_hEditFieldLabel = uilabel(app.HeaterTempCx2Panel);
            app.T_hEditFieldLabel.Text = 'T_h';
            app.T_hEditField = uieditfield(app.HeaterTempCx2Panel, 'numeric');
            app.AmbientTempCx3Panel = uipanel(app.StatexPanel);
            app.AmbientTempCx3Panel.Title = 'Ambient Temp. (C), x(3)';
            app.UIAxes_10 = uiaxes(app.AmbientTempCx3Panel);
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_10.YLim = [15 30];
            app.T_oEditFieldLabel = uilabel(app.AmbientTempCx3Panel);
            app.T_oEditFieldLabel.Text = 'T_o';
            app.T_oEditField = uieditfield(app.AmbientTempCx3Panel, 'numeric');
            app.PartialpressairkPax6Panel = uipanel(app.StatexPanel);
            app.PartialpressairkPax6Panel.Title = 'Partial press. air (kPa), x(6)';
            app.UIAxes_7 = uiaxes(app.PartialpressairkPax6Panel);
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.YLim = [0 105];
            app.p_aEditFieldLabel = uilabel(app.PartialpressairkPax6Panel);
            app.p_aEditFieldLabel.Text = 'p_a';
            app.p_aEditField = uieditfield(app.PartialpressairkPax6Panel, 'numeric');
            app.Volumem3x7Panel = uipanel(app.StatexPanel);
            app.Volumem3x7Panel.Title = 'Volume (m^3), x(7)';
            app.UIAxes_6 = uiaxes(app.Volumem3x7Panel);
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.YLim = [0 1];
            app.V_gEditFieldLabel = uilabel(app.Volumem3x7Panel);
            app.V_gEditFieldLabel.Text = 'V_g';
            app.V_gEditField = uieditfield(app.Volumem3x7Panel, 'numeric');
            app.VolumetricairflowkgsPanel = uipanel(app.StatexPanel);
            app.VolumetricairflowkgsPanel.Title = 'Volumetric airflow (kg/s)';
            app.UIAxes_20 = uiaxes(app.VolumetricairflowkgsPanel);
            zlabel(app.UIAxes_20, 'Z')
            app.UIAxes_20.YLim = [0 1];
            app.flowEditFieldLabel = uilabel(app.VolumetricairflowkgsPanel);
            app.flowEditFieldLabel.Text = 'flow';
            app.flowEditField = uieditfield(app.VolumetricairflowkgsPanel, 'numeric');
            app.SaturationpressurekPax9Panel = uipanel(app.StatexPanel);
            app.SaturationpressurekPax9Panel.Title = 'Saturation pressure (kPa), x(9)';
            app.UIAxes_15 = uiaxes(app.SaturationpressurekPax9Panel);
            zlabel(app.UIAxes_15, 'Z')
            app.UIAxes_15.YLim = [0 105];
            app.p_sEditFieldLabel = uilabel(app.SaturationpressurekPax9Panel);
            app.p_sEditFieldLabel.Text = 'p_s';
            app.p_sEditField = uieditfield(app.SaturationpressurekPax9Panel, 'numeric');
            app.Massofvaporkgx10Panel = uipanel(app.StatexPanel);
            app.Massofvaporkgx10Panel.Title = 'Mass of vapor (kg), x(10)';
            app.UIAxes_14 = uiaxes(app.Massofvaporkgx10Panel);
            zlabel(app.UIAxes_14, 'Z')
            app.UIAxes_14.YLim = [0 10];
            app.m_vEditFieldLabel = uilabel(app.Massofvaporkgx10Panel);
            app.m_vEditFieldLabel.Text = 'm_v';
            app.m_vEditField = uieditfield(app.Massofvaporkgx10Panel, 'numeric');
            app.Massofairkgx11Panel = uipanel(app.StatexPanel);
            app.Massofairkgx11Panel.Title = 'Mass of air (kg), x(11)';
            app.UIAxes_13 = uiaxes(app.Massofairkgx11Panel);
            zlabel(app.UIAxes_13, 'Z')
            app.UIAxes_13.YLim = [0 1];
            app.m_aEditFieldLabel = uilabel(app.Massofairkgx11Panel);
            app.m_aEditFieldLabel.Text = 'm_a';
            app.m_aEditField = uieditfield(app.Massofairkgx11Panel, 'numeric');
            app.Relativehumiditydecimalx8Panel = uipanel(app.StatexPanel);
            app.Relativehumiditydecimalx8Panel.Title = 'Relative humidity (decimal), x(8)';
            app.UIAxes_4 = uiaxes(app.Relativehumiditydecimalx8Panel);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.YLim = [0 1];
            app.phiEditFieldLabel = uilabel(app.Relativehumiditydecimalx8Panel);
            app.phiEditFieldLabel.Text = 'phi';
            app.phiEditField = uieditfield(app.Relativehumiditydecimalx8Panel, 'numeric');
            app.SelectoptionsPanel = uipanel(app.UIFigure);
            app.SelectoptionsPanel.Title = '1. Select options.';
            app.LivePlotCheckBox = uicheckbox(app.SelectoptionsPanel);
            app.LivePlotCheckBox.Text = 'Live Plot';
            app.LivePlotCheckBox.Value = true;
            app.max_iterationsEditField_2Label = uilabel(app.SelectoptionsPanel);
            app.max_iterationsEditField_2Label.Text = 'max_iterations';
            app.max_iterationsEditField_2 = uieditfield(app.SelectoptionsPanel, 'numeric');
            app.max_iterationsEditField_2.Value = 300;
            app.ProgressBarCheckBox = uicheckbox(app.SelectoptionsPanel);
            app.ProgressBarCheckBox.Text = 'Progress Bar';
            app.ORLabel = uilabel(app.SelectoptionsPanel);
            app.ORLabel.Text = 'OR';
            app.SimulationonlyCheckBox = uicheckbox(app.SelectoptionsPanel);
            app.SimulationonlyCheckBox.Text = 'Simulation only';
            app.UsingArduinoHardwareCheckBox = uicheckbox(app.SelectoptionsPanel);
            app.UsingArduinoHardwareCheckBox.Text = 'Using Arduino Hardware';
            app.UsingNIHardwareCheckBox = uicheckbox(app.SelectoptionsPanel);
            app.UsingNIHardwareCheckBox.Text = 'Using NI Hardware';
            app.UsingNIHardwareCheckBox.Value = true;
            app.UsingLIDARCheckBox = uicheckbox(app.SelectoptionsPanel);
            app.UsingLIDARCheckBox.Text = 'Using LIDAR';
            app.UsingRHsensorCheckBox = uicheckbox(app.SelectoptionsPanel);
            app.UsingRHsensorCheckBox.Text = 'Using RH sensor';
            app.UsingloadcellCheckBox = uicheckbox(app.SelectoptionsPanel);
            app.UsingloadcellCheckBox.Text = 'Using load cell';
            app.HeaterDropDownLabel = uilabel(app.SelectoptionsPanel);
            app.HeaterDropDownLabel.Text = 'Heater';
            app.HeaterDropDown = uidropdown(app.SelectoptionsPanel);
            app.HeaterDropDown.Items = {'none', 'ni', 'arduino'};
            app.HeaterDropDown.Value = 'ni';
            app.CreateorloadsetpointprofilePanel = uipanel(app.SelectoptionsPanel);
            app.CreateorloadsetpointprofilePanel.Title = '2. Create or load setpoint profile.';
            app.CreateNewButton = uibutton(app.CreateorloadsetpointprofilePanel, 'push');
            app.CreateNewButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewButtonPushed, true);
            app.CreateNewButton.Text = 'Create New';
            app.LoadExistingButton = uibutton(app.CreateorloadsetpointprofilePanel, 'push');
            app.LoadExistingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingButtonPushed, true);
            app.LoadExistingButton.Text = 'Load Existing';
            app.EditCurrentButton = uibutton(app.CreateorloadsetpointprofilePanel, 'push');
            app.EditCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @EditCurrentButtonPushed, true);
            app.EditCurrentButton.Text = 'Edit Current';
            app.EmergencyControlsPanel = uipanel(app.UIFigure);
            app.EmergencyControlsPanel.Title = 'Emergency Controls';
            app.EMERGENCYSTOPButton = uibutton(app.EmergencyControlsPanel, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.WhatthisdoesTextAreaLabel = uilabel(app.EmergencyControlsPanel);
            app.WhatthisdoesTextAreaLabel.Text = 'What this does:';
            app.WhatthisdoesTextArea = uitextarea(app.EmergencyControlsPanel);
            app.WhatthisdoesTextArea.Value = {'- Operation.............................STOP'; '- All heaters............................OFF'; '- Steam Injection....................DISCONTINUE'; '- PC control / data logging .....ON'; '- All other power......................OFF'};
            app.VerifyprofileandselectRUNPanel = uipanel(app.UIFigure);
            app.VerifyprofileandselectRUNPanel.Title = '3. Verify profile and select RUN';
            app.UIAxes_profile = uiaxes(app.VerifyprofileandselectRUNPanel);
            title(app.UIAxes_profile, 'Setpoint Profile')
            xlabel(app.UIAxes_profile, 'Steps')
            ylabel(app.UIAxes_profile, 'Value')
            zlabel(app.UIAxes_profile, 'Z')
            app.RunButton = uibutton(app.VerifyprofileandselectRUNPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.STOPButton = uibutton(app.VerifyprofileandselectRUNPanel, 'state');
            app.STOPButton.Text = 'STOP';
            app.LabelPanel = uipanel(app.UIFigure);
            app.LabelPanel.Title = 'Label';
            app.AdvancedResearchDryingApparatusARDALabel = uilabel(app.LabelPanel);
            app.AdvancedResearchDryingApparatusARDALabel.Text = 'Advanced Research Drying Apparatus (ARDA)';
            app.Copyright2021ForestConceptsLLCLabel = uilabel(app.LabelPanel);
            app.Copyright2021ForestConceptsLLCLabel.Text = 'Copyright 2021, Forest Concepts, LLC';
            app.BiomassPanel = uipanel(app.UIFigure);
            app.BiomassPanel.Title = 'Biomass';
            app.UIAxes_lidar = uiaxes(app.BiomassPanel);
            title(app.UIAxes_lidar, 'LIDAR')
            zlabel(app.UIAxes_lidar, 'Z')
            app.DistanceLIDARtomaterialmEditFieldLabel = uilabel(app.BiomassPanel);
            app.DistanceLIDARtomaterialmEditFieldLabel.Text = 'Distance (LIDAR to material) (m)';
            app.DistanceLIDARtomaterialmEditField = uieditfield(app.BiomassPanel, 'numeric');
            app.MasstotalmeasuredkgEditFieldLabel = uilabel(app.BiomassPanel);
            app.MasstotalmeasuredkgEditFieldLabel.Text = 'Mass (total measured) (kg)';
            app.MasstotalmeasuredkgEditField = uieditfield(app.BiomassPanel, 'numeric');
            app.RuntimePanel = uipanel(app.UIFigure);
            app.RuntimePanel.Title = 'Runtime';
            app.MoresensorsButton = uibutton(app.RuntimePanel, 'state');
            app.MoresensorsButton.ValueChangedFcn = createCallbackFcn(app, @MoresensorsButtonValueChanged, true);
            app.MoresensorsButton.Text = 'More sensors';
            app.TomonitoradditionalsensorsinanotherwindowselectLabel = uilabel(app.RuntimePanel);
            app.TomonitoradditionalsensorsinanotherwindowselectLabel.Text = 'To monitor additional sensors in another window, select "More sensors" below';
            app.ThestateofthesystemisdisplayedontherightLabel = uilabel(app.RuntimePanel);
            app.ThestateofthesystemisdisplayedontherightLabel.Text = 'The state of the system is displayed on the right.';
            app.StatusPanel = uipanel(app.RuntimePanel);
            app.StatusPanel.Title = 'Status';
            app.RunningLampLabel = uilabel(app.StatusPanel);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.StatusPanel);
            app.StartingupLampLabel = uilabel(app.StatusPanel);
            app.StartingupLampLabel.Text = 'Starting up';
            app.StartingupLamp = uilamp(app.StatusPanel);
            app.ShuttingDownLampLabel = uilabel(app.StatusPanel);
            app.ShuttingDownLampLabel.Text = 'Shutting Down';
            app.ShuttingDownLamp = uilamp(app.StatusPanel);
            app.OffLampLabel = uilabel(app.StatusPanel);
            app.OffLampLabel.Text = 'Off';
            app.OffLamp = uilamp(app.StatusPanel);
            app.StandbyLampLabel = uilabel(app.StatusPanel);
            app.StandbyLampLabel.Text = 'Standby';
            app.StandbyLamp = uilamp(app.StatusPanel);
            app.SimulationparametersPanel = uipanel(app.UIFigure);
            app.SimulationparametersPanel.Title = 'Simulation parameters';
            app.iEditFieldLabel = uilabel(app.SimulationparametersPanel);
            app.iEditFieldLabel.Text = 'i';
            app.iEditField = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.max_iterationsEditFieldLabel = uilabel(app.SimulationparametersPanel);
            app.max_iterationsEditFieldLabel.Text = 'max_iterations';
            app.max_iterationsEditField = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.ElapsedTimeTextAreaLabel = uilabel(app.SimulationparametersPanel);
            app.ElapsedTimeTextAreaLabel.Text = 'Elapsed Time';
            app.ElapsedTimeTextArea = uitextarea(app.SimulationparametersPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arda_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end