classdef more_sensors < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MoresensorsUIFigure   matlab.ui.Figure
        AveragebeddepthPanel  matlab.ui.container.Panel
        UIAxes5               matlab.ui.control.UIAxes
        PressureinbedPanel    matlab.ui.container.Panel
        UIAxes4               matlab.ui.control.UIAxes
        IRTempCPanel          matlab.ui.container.Panel
        UIAxes2               matlab.ui.control.UIAxes
        MassPanel             matlab.ui.container.Panel
        UIAxes3               matlab.ui.control.UIAxes
        ThermocouplesCinbedandpostbiomassPanel  matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = public)

        main_app

    end
    
    methods (Access = public)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            
            arguments

                app
                main
                
            end

            app.main_app = main;


        end

        % Close request function: MoresensorsUIFigure
        function MoresensorsUIFigureCloseRequest(app, event)
                        
                response = uiconfirm(app.MoresensorsUIFigure, "Close?",...
                    "Confirm",...
                    "Options", ["Yes", "Cancel"], ...
                    "CancelOption", "Cancel",...
                    "DefaultOption", "Yes");

                if strcmp(response, "Yes")
                    
                    app.main_app.MoresensorsButton.Enable = "on";
                    app.main_app.MoresensorsButton.Value = false;
                    delete(app)

                end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MoresensorsUIFigure = uifigure('Visible', 'off');
            app.MoresensorsUIFigure.Name = 'More sensors';
            app.MoresensorsUIFigure.CloseRequestFcn = createCallbackFcn(app, @MoresensorsUIFigureCloseRequest, true);
            app.ThermocouplesCinbedandpostbiomassPanel = uipanel(app.MoresensorsUIFigure);
            app.ThermocouplesCinbedandpostbiomassPanel.Title = 'Thermocouples, C (in bed and post biomass)';
            app.UIAxes = uiaxes(app.ThermocouplesCinbedandpostbiomassPanel);
            title(app.UIAxes, 'Temperature')
            xlabel(app.UIAxes, 't (sec)')
            ylabel(app.UIAxes, 'T (C)')
            zlabel(app.UIAxes, 'Z')
            app.MassPanel = uipanel(app.MoresensorsUIFigure);
            app.MassPanel.Title = 'Mass';
            app.UIAxes3 = uiaxes(app.MassPanel);
            title(app.UIAxes3, 'Mass of Biomass')
            xlabel(app.UIAxes3, 't (sec)')
            ylabel(app.UIAxes3, 'mass (kg)')
            zlabel(app.UIAxes3, 'Z')
            app.IRTempCPanel = uipanel(app.MoresensorsUIFigure);
            app.IRTempCPanel.Title = 'IR Temp., C';
            app.UIAxes2 = uiaxes(app.IRTempCPanel);
            title(app.UIAxes2, 'IR Sensors')
            xlabel(app.UIAxes2, 't (sec)')
            ylabel(app.UIAxes2, 'T (C)')
            zlabel(app.UIAxes2, 'Z')
            app.PressureinbedPanel = uipanel(app.MoresensorsUIFigure);
            app.PressureinbedPanel.Title = 'Pressure (in bed)';
            app.UIAxes4 = uiaxes(app.PressureinbedPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 't (sec)')
            ylabel(app.UIAxes4, 'p (kPa)')
            zlabel(app.UIAxes4, 'Z')
            app.AveragebeddepthPanel = uipanel(app.MoresensorsUIFigure);
            app.AveragebeddepthPanel.Title = 'Average bed depth';
            app.UIAxes5 = uiaxes(app.AveragebeddepthPanel);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 't (sec)')
            ylabel(app.UIAxes5, 'd (mm)')
            zlabel(app.UIAxes5, 'Z')
            % Show the figure after all components are created
            app.MoresensorsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = more_sensors(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MoresensorsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MoresensorsUIFigure)
        end
    end
end